classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        SecondsLabel                   matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        EditField_2                    matlab.ui.control.EditField
        EditField                      matlab.ui.control.EditField
        SETButton                      matlab.ui.control.Button
        COMSelectDropDown              matlab.ui.control.DropDown
        COMSelectDropDownLabel         matlab.ui.control.Label
        DHTPanel                       matlab.ui.container.Panel
        Label_13                       matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        LDRPanel_2                     matlab.ui.container.Panel
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_1                        matlab.ui.control.Label
        disconnectButton               matlab.ui.control.Button
        connectButton                  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        LogTab                         matlab.ui.container.Tab
        DateDropDown                   matlab.ui.control.DropDown
        DateDropDownLabel              matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        Label_14                       matlab.ui.control.Label
        SettingTab                     matlab.ui.container.Tab
        HumiditythresholdEditField     matlab.ui.control.NumericEditField
        HumiditythresoldLabel          matlab.ui.control.Label
        BrightnessthrehsoldEditField   matlab.ui.control.NumericEditField
        BrightnessthresoldLabel        matlab.ui.control.Label
        LightDelayTimeMinuteEditField  matlab.ui.control.NumericEditField
        LightDelayTimeMinuteEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        COM;
        f=0;
        connected_flag=0;
        light_flag=0;
        fog_flag=0;
        MyTimer = 0;
        
        start_time=now;
        dtStr=datetime;
        commandBuffer=string({});
        drawFlag=0;
        L=[0 0 0];
        H=[0 0 0];
        T=[0 0 0];
    end

    methods (Access = private)

        function TimerCallback(app,~,~)                
                if(app.drawFlag)
                    try
                        writeline(app.COM,"active");                
                        inp=readline(app.COM);               
                        replace(inp,"nan","0");
                        data=split(inp," ");                       
                        drawaxe(app,data);
                        light_test(app,str2double(data(1:9)));
                        fog_test(app,str2double(data(1:9)));
                    catch  
                        disp("readError");                        
                    end
                app.drawFlag=~app.drawFlag;
                else 
                app.sendCommand();
                app.drawFlag=~app.drawFlag;
                end               
        end 


        function light_test(app,data)
           %disp(data);
           if(app.connected_flag==0)
               return;
           end
           if(app.light_flag==0)
                if(sum(data(1:3))/3<app.BrightnessthrehsoldEditField.Value)                    
                    app.commandBuffer(end+1)="light_on";
                    logger(app,'light_on');
                    
                    lightDelayTimer=timer('StartDelay', app.LightDelayTimeMinuteEditField.Value*60, 'TasksToExecute', 1,'BusyMode','drop', 'ExecutionMode', 'singleShot');
                    lightDelayTimer.TimerFcn=@app.lightDelayFcn;
                    start(lightDelayTimer);                   
                    app.light_flag=1;
                end               
           end
        end

        function lightDelayFcn(app,~,~)
            app.light_flag=0;
%             disp("app.flag"+app.light_flag);
              
            %writeline(app.COM,"light_off");
            app.commandBuffer(end+1)="light_off";
            logger(app,"light_off");
        end

        function fog_test(app,data)
           %disp(data);
           if(app.connected_flag==0)
               return;
           end
           if(app.fog_flag==0)
                if(sum(data(4:2:8))/3>80)

                    app.commandBuffer(end+1)="fog_off";
                    logger(app,'fog_off');

                    FogDelayTimer=timer('StartDelay', 30*60, 'TasksToExecute', 1,'BusyMode','drop', 'ExecutionMode', 'singleShot');
                    FogDelayTimer.TimerFcn=@app.FogDelayFcn;
                    start(FogDelayTimer);                   
                    app.fog_flag=1;
                end               
           end
        end
        function FogDelayFcn(app,~,~)
            app.fog_flag=0;
%             disp("app.flag"+app.light_flag);
              
            %writeline(app.COM,"light_off");
            app.commandBuffer(end+1)="fog_on";
            logger(app,"fog_on");
        end

        function drawaxe(app,data)
                if size(app.L,1)>300
                    app.L=[0 0 0];
                    app.H=[0 0 0];
                    app.T=[0 0 0];
                end
                %LDR
                app.Label_1.Text=floor(str2double(data(1))/1024*100)+"%";
                app.Label_2.Text=floor(str2double(data(2))/1024*100)+"%";
                app.Label_3.Text=floor(str2double(data(3))/1024*100)+"%";
                %DHT
                app.Label_4.Text=data(4);
                app.Label_5.Text=data(5);
                app.Label_6.Text=data(6);
                app.Label_7.Text=data(7);
                app.Label_8.Text=data(8);
                app.Label_9.Text=data(9);


                app.L=[app.L; str2double(data(1)) str2double(data(2)) str2double(data(3))];
                app.H=[app.H; str2double(data(4)) str2double(data(6)) str2double(data(8))];
                app.T=[app.T; str2double(data(5)) str2double(data(7)) str2double(data(9))];
                
                plot(app.UIAxes,app.L,Color='r');
                
                plot(app.UIAxes2,app.H,Color='g');
                plot(app.UIAxes3,app.T,Color='b');


                hold(app.UIAxes, "off");
                hold(app.UIAxes2, "off");         
                hold(app.UIAxes3, "off");
        end


        function p_tFcn(app,~,~)

            app.commandBuffer(end+1)="pump_on";
            logger(app,"pump_on");                   
            
            close_timer=timer('StartDelay',str2double(app.EditField_2.Value), 'TasksToExecute', 1,'BusyMode','queue', 'ExecutionMode', 'singleShot');
            close_timer.TimerFcn=@app.p_close_timerFcn;      
            start(close_timer);
            
        end
        
        function p_close_timerFcn(app,~,~)
            app.commandBuffer(end+1)="pump_off";
            logger(app,"pump_off");
        end
        
        function logger(app,mes)
            app.dtStr=datetime('now');
            f_log=fopen(strcat(datestr(app.dtStr,'yyyy-mm-dd'),'.txt'),'A');
            fprintf(f_log,'%s %s\n',app.dtStr, mes);          
            fclose(f_log);
            disp(mes);            
        end
        function test(app)
            app.dtStr=datetime('now');
            f_log=fopen("testlog.txt",'A');
            ff=getpinstatus(app.COM);
            fprintf(f_log,'%s> %d %d %d %d \n',app.dtStr,ff.ClearToSend,ff.DataSetReady,ff.CarrierDetect,ff.RingIndicator);
            disp(ff);
            fclose(f_log);       
                
            
        end


        function sendCommand(app)
            if(~isempty(app.commandBuffer))
                 try
                    writeline(app.COM,app.commandBuffer(1));
                    app.commandBuffer=app.commandBuffer(2:end);                
                    
                 catch exception
                     disp("woc");
                    disp(exception);
                 end
            end
                
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MyTimer=timer('StartDelay',0, 'Period', 0.1, 'TasksToExecute', Inf,'BusyMode','drop', 'ExecutionMode', 'fixedSpacing');
            app.MyTimer.TimerFcn=@app.TimerCallback;
            axis(app.UIAxes,[1 inf 0 1024]);
            hold(app.UIAxes, "on");
            axis(app.UIAxes2,[0 inf 0 100]);
            hold(app.UIAxes2, "on");            
            axis(app.UIAxes3,[0 inf 0 40]);
            hold(app.UIAxes3, "on");
            xlim(app.UIAxes,[1 500]);
            xlim(app.UIAxes2,[1 500]);
            xlim(app.UIAxes3,[1 500]);
           
            
            app.dtStr = datestr(app.dtStr, '[yyyy-mm-dd HH:MM:SS.FFF]');

            logger(app,"started");%log
            
            %clear serial port
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            app.COMSelectDropDown.Items=serialportlist();
        end

        % Button pushed function: connectButton
        function connectButtonPushed(app, event)
            if(app.COMSelectDropDown.Value~="")      %ensure COM is seleted 
                
                app.COM=serialport(app.COMSelectDropDown.Value,9600,"Timeout",1);                
                configureTerminator(app.COM,"LF");
                 
                disp("COM connect");
                logger(app,"COM connect");
                  
                %writeline(app.COM,"active");
                %readline(app.COM);
                start(app.MyTimer);
                
                app.connected_flag=1;
                app.commandBuffer(end+1)="fog_on";
                app.logger("fog_on");
            end   
           
            
        end

        % Button pushed function: disconnectButton
        function disconnectButtonPushed(app, event)
            app.f=1;
            app.connected_flag=0;
            stop(app.MyTimer);
            app.COM='';
            disp("closed");
            logger(app,"COM closed");
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end

        end

        % Drop down opening function: COMSelectDropDown
        function COMSelectDropDownOpening(app, event)
            app.COMSelectDropDown.Items=serialportlist();
        
        end

        % Button pushed function: SETButton
        function SETButtonPushed(app, event)
           
           p_timer=timer('StartDelay',0, 'Period', 60*str2double(app.EditField.Value)+ str2double(app.EditField_2.Value), 'TasksToExecute', Inf,'BusyMode','drop', 'ExecutionMode', 'fixedSpacing');
           p_timer.TimerFcn=@app.p_tFcn;           
           p_timer.UserData="pump";
           start(p_timer);

           disp("started");
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(timerfind);            
            delete(app);

        end

        % Button down function: LogTab
        function LogTabButtonDown(app, event)
            f_log=fopen(strcat(datestr(datetime('now'),'yyyy-mm-dd'),'.txt'),"r");
           
            app.TextArea.Value=fscanf(f_log,"%c");
        end

        % Drop down opening function: DateDropDown
        function DateDropDownOpening(app, event)
            app.DateDropDown.Items={};
            d=dir(fullfile('*.txt'));
            for i= 1 : length(d)
                app.DateDropDown.Items=[app.DateDropDown.Items d(i).name];
            end
        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            value = app.DateDropDown.Value;
            disp(value);
            f_log=fopen(value,"r");
           
            app.TextArea.Value=fscanf(f_log,"%c");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '室內智慧植物造景裝置';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UIAxes3 = uiaxes(app.MainTab);
            title(app.UIAxes3, 'Tempture')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.MainTab);
            title(app.UIAxes2, 'Humidity')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.MainTab);
            title(app.UIAxes, 'LDR')
            app.connectButton = uibutton(app.MainTab, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connectButton.Text = 'connect';
            app.disconnectButton = uibutton(app.MainTab, 'push');
            app.disconnectButton.ButtonPushedFcn = createCallbackFcn(app, @disconnectButtonPushed, true);
            app.disconnectButton.Text = 'disconnect';
            app.LDRPanel_2 = uipanel(app.MainTab);
            app.LDRPanel_2.Title = 'LDR';
            app.Label_1 = uilabel(app.LDRPanel_2);
            app.Label_1.Text = {'Lable'; ''};
            app.Label_2 = uilabel(app.LDRPanel_2);
            app.Label_3 = uilabel(app.LDRPanel_2);
            app.DHTPanel = uipanel(app.MainTab);
            app.DHTPanel.Title = 'DHT';
            app.Label_4 = uilabel(app.DHTPanel);
            app.Label_4.Text = {'Lable'; ''};
            app.Label_5 = uilabel(app.DHTPanel);
            app.Label_6 = uilabel(app.DHTPanel);
            app.Label_6.Text = {'Label'; ''};
            app.Label_7 = uilabel(app.DHTPanel);
            app.Label_7.Text = {'Label'; ''};
            app.Label_8 = uilabel(app.DHTPanel);
            app.Label_8.Text = {'Label'; ''};
            app.Label_9 = uilabel(app.DHTPanel);
            app.Label_9.Text = {'Label'; ''};
            app.Label_12 = uilabel(app.DHTPanel);
            app.Label_12.Text = '濕度';
            app.Label_13 = uilabel(app.DHTPanel);
            app.Label_13.Text = '溫度';
            app.COMSelectDropDownLabel = uilabel(app.MainTab);
            app.COMSelectDropDownLabel.Text = 'COM Select';
            app.COMSelectDropDown = uidropdown(app.MainTab);
            app.COMSelectDropDown.Items = {};
            app.COMSelectDropDown.DropDownOpeningFcn = createCallbackFcn(app, @COMSelectDropDownOpening, true);
            app.COMSelectDropDown.Value = {};
            app.SETButton = uibutton(app.MainTab, 'push');
            app.SETButton.ButtonPushedFcn = createCallbackFcn(app, @SETButtonPushed, true);
            app.SETButton.Text = {'SET'; ''};
            app.EditField = uieditfield(app.MainTab, 'text');
            app.EditField.Value = '3';
            app.EditField_2 = uieditfield(app.MainTab, 'text');
            app.EditField_2.Value = '3';
            app.Label_10 = uilabel(app.MainTab);
            app.Label_10.Text = '每隔';
            app.Label_11 = uilabel(app.MainTab);
            app.Label_11.Text = '分 動作';
            app.SecondsLabel = uilabel(app.MainTab);
            app.SecondsLabel.Text = '(Seconds)';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.LogTab.ButtonDownFcn = createCallbackFcn(app, @LogTabButtonDown, true);
            app.Label_14 = uilabel(app.LogTab);
            app.Label_14.Text = '';
            app.TextArea = uitextarea(app.LogTab);
            app.TextArea.Editable = 'off';
            app.DateDropDownLabel = uilabel(app.LogTab);
            app.DateDropDownLabel.Text = {'Date'; ''};
            app.DateDropDown = uidropdown(app.LogTab);
            app.DateDropDown.Items = {};
            app.DateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DateDropDownOpening, true);
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.DateDropDown.Value = {};
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.LightDelayTimeMinuteEditFieldLabel = uilabel(app.SettingTab);
            app.LightDelayTimeMinuteEditFieldLabel.Text = 'LightDelayTime (Minute)';
            app.LightDelayTimeMinuteEditField = uieditfield(app.SettingTab, 'numeric');
            app.LightDelayTimeMinuteEditField.Value = 60;
            app.BrightnessthresoldLabel = uilabel(app.SettingTab);
            app.BrightnessthresoldLabel.Text = 'Brightness threhsold       ';
            app.BrightnessthrehsoldEditField = uieditfield(app.SettingTab, 'numeric');
            app.BrightnessthrehsoldEditField.Value = 300;
            app.HumiditythresoldLabel = uilabel(app.SettingTab);
            app.HumiditythresoldLabel.Text = 'Humidity threshold (%)   ';
            app.HumiditythresholdEditField = uieditfield(app.SettingTab, 'numeric');
            app.HumiditythresholdEditField.Value = 80;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end